classdef IngredientDB < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                    matlab.ui.Figure
      FileMenu                    matlab.ui.container.Menu
      OpenMenu                    matlab.ui.container.Menu
      SaveFormulationMenu         matlab.ui.container.Menu
      TrimSwitch                  matlab.ui.control.RockerSwitch
      TrimSwitchLabel             matlab.ui.control.Label
      ClearButton                 matlab.ui.control.Button
      ShowCurveButton             matlab.ui.control.Button
      EstimatedtBetaEditField     matlab.ui.control.NumericEditField
      tBetaLabel                  matlab.ui.control.Label
      NaClLabel                   matlab.ui.control.Label
      GluconicEditField           matlab.ui.control.NumericEditField
      GluconicEditFieldLabel      matlab.ui.control.Label
      GluconicPercentEditField    matlab.ui.control.NumericEditField
      ProtGluEditField            matlab.ui.control.NumericEditField
      mMLabel_2                   matlab.ui.control.Label
      mMLabel                     matlab.ui.control.Label
      PercentLabel                matlab.ui.control.Label
      PhosphoricPercentEditField  matlab.ui.control.NumericEditField
      AscorbicPercentEditField    matlab.ui.control.NumericEditField
      MalicPercentEditField       matlab.ui.control.NumericEditField
      CitricPercentEditField      matlab.ui.control.NumericEditField
      AceticPercentEditField      matlab.ui.control.NumericEditField
      LacticPercentEditField      matlab.ui.control.NumericEditField
      PhosphoricEditField         matlab.ui.control.NumericEditField
      PhosphoricEditFieldLabel    matlab.ui.control.Label
      ProtonatedLabel             matlab.ui.control.Label
      ProtAscEditField            matlab.ui.control.NumericEditField
      ProtMalEditField            matlab.ui.control.NumericEditField
      ProtCitEditField            matlab.ui.control.NumericEditField
      ProtAceEditField            matlab.ui.control.NumericEditField
      ProtLacEditField            matlab.ui.control.NumericEditField
      AscorbicEditField           matlab.ui.control.NumericEditField
      AscorbicEditFieldLabel      matlab.ui.control.Label
      MalicEditField              matlab.ui.control.NumericEditField
      MalicEditFieldLabel         matlab.ui.control.Label
      CitricEditField             matlab.ui.control.NumericEditField
      CitricEditFieldLabel        matlab.ui.control.Label
      FormulationLabel            matlab.ui.control.Label
      FormulationTable            matlab.ui.control.Table
      EstimatedpHvalueEditField   matlab.ui.control.NumericEditField
      CalculatedpHvalueLabel      matlab.ui.control.Label
      AceticEditField             matlab.ui.control.NumericEditField
      AceticEditFieldLabel        matlab.ui.control.Label
      LacticEditField             matlab.ui.control.NumericEditField
      LacticEditFieldLabel        matlab.ui.control.Label
      AverageNaClEditField        matlab.ui.control.NumericEditField
      IngredientsListBox          matlab.ui.control.ListBox
      IngredientsListBoxLabel     matlab.ui.control.Label
   end

   
   properties (Access = private)
      ABDvar = ABD.empty;     %empty list of ingredient ADB class variables
      subABD = ABD.empty;     %ABD list for formulation ingredients
      trimbool = 0;
      Version = '1.302';
      ABDver = '220701'
   end
   
   methods (Access = private)
      
      function setSubABD(app,indxlist)
         nnames = length(indxlist);          %number selected
         tempABD = ABD.empty;                %empty temp ingredient list
         sumofIS = 0;
         sublistnames = {app.subABD(:).name}; %get names in cell array
         for i=1:nnames                      %for each item selected
            indxname = {app.ABDvar(indxlist(i)).name}; %get name of item
            subindx = find(strcmp(indxname,sublistnames)); %search sublist
            if subindx                       %if item found
               tempABD(i) = app.subABD(subindx); %use existing ingredient
            else                             %not found
               tempABD(i) = app.ABDvar(indxlist(i)); %add ingredient
            end
            sumofIS = sumofIS + tempABD(i).titration_IS;
         end
         meanIS = sumofIS/nnames;
         app.AverageNaClEditField.Value = meanIS*5.84;
         app.subABD = tempABD;               %replace sublist
         app.post_subABD();                  %post results
      end
      
      function post_subABD(app)
         namearray = {app.subABD(:).name}';   %get name array
         concarray = [app.subABD(:).conc]';   %get concentrations
         ingtable  = table(namearray,concarray); %make a table to post
         app.FormulationTable.Data = ingtable; %post table
      end    

      function acidTable = getAcidTable(app)
         aceConc = app.AceticEditField.Value/1000; %get acetic in molar
         lacConc = app.LacticEditField.Value/1000; %get lactic in molar
         citConc = app.CitricEditField.Value/1000; %get citric in molar
         malConc = app.MalicEditField.Value/1000;  %get malic in molar
         ascConc = app.AscorbicEditField.Value/1000; %get ascorbic molar
         phsConc = app.PhosphoricEditField.Value/1000; %get phos molar
         gluConc = app.GluconicEditField.Value/1000; %get gluconic molar
         %make concentration vec Nx1
         Conc = [aceConc;lacConc;citConc;citConc;citConc;malConc;...
            malConc;ascConc;phsConc;phsConc;phsConc;gluConc];        
         IS = app.AverageNaClEditField.Value/5.84; %ionic strenght from % NaCl
         %adjust pKs for each buffer
         pKace = AdjpKa(4.76,IS);         %adjust pKa for acetic
         pKlac = AdjpKa(3.86,IS);         %adjust pKa for lactic
         pKcit1 = AdjpKa(3.128,IS);
         pKcit2 = AdjpKa(4.761,IS);
         pKcit3 = AdjpKa(6.396,IS);
         pKmal1 = AdjpKa(3.40,IS);
         pKmal2 = AdjpKa(5.20,IS);
         pKasc = AdjpKa(4.10,IS);
         pKphs1 = AdjpKa(2.14,IS);
         pKphs2 = AdjpKa(7.2,IS);
         pKphs3 = AdjpKa(12.37,IS);
         pKglu = AdjpKa(3.86,IS);
         %make pK vec Nx1
         pK = [pKace;pKlac;pKcit1;pKcit2;pKcit3;pKmal1;pKmal2;pKasc;...
            pKphs1;pKphs2;pKphs3;pKglu];             
         a_b = {'a';'a';'a';'a';'a';'a';'a';'a';'a';'a';'a';'a'}; %make a_b vec Nx1
         acidTable = table(Conc,pK,a_b);  %build table 
      end
      
      function formulaABD = getFormulaABD(app)
         %returns ABD class with comined buffers, adjC, and calc pH
         %NOTE: All calculations are done in the ABD except adjusting 
         %  the totalBC to tBeta. BY DEFAULT pH vec is 2:0.05:12
         %  SO, there is NO CURVE TRIMMING... (for sugars, etc.)
         %  Possible solution:
         %  get curve after additions then trim then manually calc
         %     the pH and tBeta?
         formulaABD = ABD;                %get empty ABD class
         nitems = size(app.subABD,2);        %number of items in subABD
         if nitems == 0
            acidTable = app.getAcidTable();  %get acid buffer(s)
            formulaABD = formulaABD.add_buffertable(acidTable); %add acid buffers
         else
            for i=1:nitems                   %for each item in subABD
               formulaABD = formulaABD.add_ABD(app.subABD(i)); %combine ABDs
               acidTable = app.getAcidTable();  %get acid buffer(s)
               formulaABD = formulaABD.add_buffertable(acidTable); %add acid buffers
            end
         end
         if app.trimbool
            formulaABD = app.set_trimArea(formulaABD);
         end
         formulaABD.totalBC = 100*(formulaABD.totalBC-0.02);
      end
      
      function UpdateProt(app,pH)
         IS = app.AverageNaClEditField.Value/5.84;
         %update Lactic
         conc = app.LacticEditField.Value;
         pK = 3.86;
         prot = CalcProtMono(IS,pH,pK,conc);
         app.ProtLacEditField.Value = prot;
         %update Acetic
         conc = app.AceticEditField.Value;
         pK = 4.76;
         prot = CalcProtMono(IS,pH,pK,conc);
         app.ProtAceEditField.Value = prot;
         %update Citric
         conc = app.CitricEditField.Value;
         pKo1 = 3.128;
         pKo2 = 4.761;
         pKo3 = 6.396;
         rval = CalcTriproticStruct(conc,pH,pKo1,pKo2,pKo3,IS);
         app.ProtCitEditField.Value = rval.Prot;
         %update Malic
         conc = app.MalicEditField.Value;
         pKo1 = 3.40;
         pKo2 = 5.20;
         rval = CalcDiproticStruct(conc,pH,pKo1,pKo2,IS);
         app.ProtMalEditField.Value = rval.Prot;
         %update Asc
         conc = app.AscorbicEditField.Value;
         pK = 4.17;
         prot = CalcProtMono(IS,pH,pK,conc);
         app.ProtAscEditField.Value = prot;
         %update Gluconic
         conc = app.GluconicEditField.Value;
         pK = 3.86;
         prot = CalcProtMono(IS,pH,pK,conc);
         app.ProtGluEditField.Value = prot;
      end
        
     function set_tBeta(app,tbvalue)
        if tbvalue < 0.001
           tbvalue = 0;
        end
        app.EstimatedtBetaEditField.Value = tbvalue;
     end
        
     function postFormulaABD(app,formulaABD)
         pH = formulaABD.pH;
         app.EstimatedpHvalueEditField.Value = pH; %post formula pH
         tBeta = formulaABD.totalBC;
         app.set_tBeta(tBeta);
         app.UpdateProt(pH);
      end
      
      function resABD = set_trimArea(~,trmABD)
         %NaClpct = app.AverageNaClEditField.Value;
         NaClpct = 0;
         ABmat = trmABD.buffer_table{:,1:2};
         pHvec = 2:0.05:12;
         BCcurve = BetaModel_AB(ABmat,NaClpct,pHvec');
         tempBC = equalBCends(BCcurve);
         SCBCdata = SCBCfit_area(tempBC,15);
         resABD = trmABD;
         resABD.totalBC = SCBCdata.area;
      end
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         app.TrimSwitch.Value = 'Off';
      end

      % Menu selected function: OpenMenu
      function OpenMenuSelected(app, event)
         [file, path] = uigetfile('*.mat','open ingredient file', ...
             'MultiSelect','on');         %multiselectd open
         if path                          %check file path
            file = cellstr(file);         %because if one file, not cellstr
            nfiles = size(file,2);        %get number of files opened
            for i=1:nfiles                %for each file
               tempname = file{i};
               var = fullfile(path, file{i}); %make path
               temp = load(var);          %load the variable into matlab
               try
               app.ABDvar(i) = temp.ingredient; %get ingredient data
               catch
                  msgbox(['Error loading database file:',...
                  tempname], ...
                  'File error');
                  return
               end
            end
            namearray = {app.ABDvar(:).name}; %make cell array of names
            app.IngredientsListBox.Items = namearray; %post to list
         end
      end

      % Value changed function: IngredientsListBox
      function IngredientsListBoxValueChanged(app, event)
         %list box is multiselect
         value = app.IngredientsListBox.Value; %get item(s) selected
         nnames = size(value,2);          %number of items
         indxlist = zeros(nnames,1);      %make empty list
         namearray = {app.ABDvar(:).name}; %get name array
         for i = 1:nnames                 %for each name
            indxlist(i) = find(strcmp(value(i),namearray)); %get indices
         end
         %NOTE: setSubABD sets the NaCl box...
         app.setSubABD(indxlist); %generate a sub list of ingredients
         app.post_subABD();           %post ingredient sublist 
         formulaABD = app.getFormulaABD();  %combine subABD(s)
         app.postFormulaABD(formulaABD);
         
      end

      % Value changed function: LacticEditField
      function LacticEditFieldValueChanged(app, event)
         value = app.LacticEditField.Value;
         percent = 90.08*value*0.1/1000;
         app.LacticPercentEditField.Value = percent;
         formulaABD = app.getFormulaABD();
         app.postFormulaABD(formulaABD);
      end

      % Cell edit callback: FormulationTable
      function FormulationTableCellEdit(app, event)
         indices = event.Indices;         %indices: row, col
         indx = indices(1);               %row = ingredient index
         newData = event.NewData;         %new val in box
         if isnumeric(newData)            %check for valid conc number
            if newData>0                  %conc has to be greater than 0
               app.subABD(indx) = ...     %set new conc
                  app.subABD(indx).reset_ingredientconc(newData);
            end
         end
         formulaABD = app.getFormulaABD(); %get new formula
         app.postFormulaABD(formulaABD);
      end

      % Value changed function: AceticEditField
      function AceticEditFieldValueChanged(app, event)
         value = app.AceticEditField.Value;
         percent = 60.052*value*0.1/1000;
         app.AceticPercentEditField.Value = percent;
         formulaABD = app.getFormulaABD();
         app.postFormulaABD(formulaABD);
      end

      % Menu selected function: SaveFormulationMenu
      function SaveFormulationMenuSelected(app, event)
         ingredient = app.getFormulaABD();
         ingredient
         try
            [file, path] = uiputfile('*.mat','Save ABD File');     %get name and path
            fullname = fullfile(path,file);
            save(fullname,"ingredient"); 
         catch
            msgbox('Error writing ABD file, please check location',...
               'Write Error');
         end   
      end

      % Value changed function: CitricEditField
      function CitricEditFieldValueChanged(app, event)
         value = app.CitricEditField.Value;
         percent = 192.124*value*0.1/1000;
         app.CitricPercentEditField.Value = percent;
         formulaABD = app.getFormulaABD();
         app.postFormulaABD(formulaABD);
      end

      % Value changed function: MalicEditField
      function MalicEditFieldValueChanged(app, event)
         value = app.MalicEditField.Value;
         percent = 134.0874*value*0.1/1000;
         app.MalicPercentEditField.Value = percent;
         formulaABD = app.getFormulaABD();
         app.postFormulaABD(formulaABD);
      end

      % Value changed function: AscorbicEditField
      function AscorbicEditFieldValueChanged(app, event)
         value = app.AscorbicEditField.Value;
         percent = 176.12*value*0.1/1000;
         app.AscorbicPercentEditField.Value = percent;
         formulaABD = app.getFormulaABD();
         app.postFormulaABD(formulaABD);
      end

      % Value changed function: PhosphoricEditField
      function PhosphoricEditFieldValueChanged(app, event)
         value = app.PhosphoricEditField.Value;
         percent = 97.994*value*0.1/1000;
         app.PhosphoricPercentEditField.Value = percent;
         formulaABD = app.getFormulaABD();
         app.postFormulaABD(formulaABD);
      end

      % Value changed function: LacticPercentEditField
      function LacticPercentEditFieldValueChanged(app, event)
         value = app.LacticPercentEditField.Value;
         molar = value/(90.08*0.1);
         app.LacticEditField.Value = molar*1000;
         app.LacticEditFieldValueChanged();
      end

      % Value changed function: AceticPercentEditField
      function AceticPercentEditFieldValueChanged(app, event)
         value = app.AceticPercentEditField.Value;
         molar = value/(60.052*0.1);
         app.AceticEditField.Value = molar*1000;
         app.AceticEditFieldValueChanged();
      end

      % Value changed function: CitricPercentEditField
      function CitricPercentEditFieldValueChanged(app, event)
         value = app.CitricPercentEditField.Value;
         molar = value/(192.124*0.1);
         app.CitricEditField.Value = molar*1000;
         app.CitricEditFieldValueChanged();
      end

      % Value changed function: MalicPercentEditField
      function MalicPercentEditFieldValueChanged(app, event)
         value = app.MalicPercentEditField.Value;
         molar = value/(134.0874*0.1);
         app.MalicEditField.Value = molar*1000;
         app.MalicEditFieldValueChanged();
      end

      % Value changed function: AscorbicPercentEditField
      function AscorbicPercentEditFieldValueChanged(app, event)
         value = app.AscorbicPercentEditField.Value;
         molar = value/(176.12*0.1);
         app.AscorbicEditField.Value = molar*1000;
         app.AscorbicEditFieldValueChanged();
      end

      % Value changed function: PhosphoricPercentEditField
      function PhosphoricPercentEditFieldValueChanged(app, event)
         value = app.PhosphoricPercentEditField.Value;
         molar = value/(97.994*0.1);
         app.PhosphoricEditField.Value = molar*1000;
         app.PhosphoricEditFieldValueChanged();
      end

      % Value changed function: GluconicPercentEditField
      function GluconicPercentEditFieldValueChanged(app, event)
            value = app.GluconicPercentEditField.Value;
            molar = value/(196.16*0.1);
            app.GluconicEditField.Value = molar*1000;
            app.GluconicEditFieldValueChanged();
      end

      % Value changed function: GluconicEditField
      function GluconicEditFieldValueChanged(app, event)
         value = app.GluconicEditField.Value;
         percent = 196.16*value*0.1/1000;
         app.GluconicPercentEditField.Value = percent;
         formulaABD = app.getFormulaABD();
         app.postFormulaABD(formulaABD);
      end

      % Button pushed function: ShowCurveButton
      function ShowCurveButtonPushed(app, event)
         ingredient = app.getFormulaABD();   %note curvebool == 0 
         if app.trimbool                     %because all curves here 
            plotABDareaTrim(ingredient,0);   %are composite from 
         else                                %formula, not original!
            plotABDarea(ingredient,0);
         end
      end

      % Value changed function: AverageNaClEditField
      function AverageNaClEditFieldValueChanged(app, event)
         if ~isempty(app.subABD)
            app.IngredientsListBoxValueChanged();
            %reset salt back to it's original value...
            msgbox(['The value is set by the mean of the ingredient' ...
               ' titration NaCl values'], ...
               'Salt Value');
            return;
         end
         formulaABD = app.getFormulaABD();
         app.postFormulaABD(formulaABD);
      end

      % Button pushed function: ClearButton
      function ClearButtonPushed(app, event)
         app.AverageNaClEditField.Value = 0;
         app.set_tBeta(0);
         app.subABD = ABD.empty;
         app.post_subABD();
         formulaABD = app.getFormulaABD();
         app.postFormulaABD(formulaABD);
      end

      % Value changed function: TrimSwitch
      function TrimSwitchValueChanged(app, event)
         value = app.TrimSwitch.Value;
         switch value
            case 'On'
               app.trimbool = 1;
            case 'Off'
               app.trimbool = 0;
         end
         formulaABD = app.getFormulaABD();
         app.postFormulaABD(formulaABD);
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'MATLAB App';
         app.FileMenu = uimenu(app.UIFigure);
         app.FileMenu.Text = 'File';
         app.OpenMenu = uimenu(app.FileMenu);
         app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
         app.OpenMenu.Text = 'Open';
         app.SaveFormulationMenu = uimenu(app.FileMenu);
         app.SaveFormulationMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveFormulationMenuSelected, true);
         app.SaveFormulationMenu.Text = 'Save Formulation';
         app.IngredientsListBoxLabel = uilabel(app.UIFigure);
         app.IngredientsListBoxLabel.Text = 'Ingredients';
         app.IngredientsListBox = uilistbox(app.UIFigure);
         app.IngredientsListBox.Items = {};
         app.IngredientsListBox.Multiselect = 'on';
         app.IngredientsListBox.ValueChangedFcn = createCallbackFcn(app, @IngredientsListBoxValueChanged, true);
         app.IngredientsListBox.Value = {};
         app.AverageNaClEditField = uieditfield(app.UIFigure, 'numeric');
         app.AverageNaClEditField.ValueChangedFcn = createCallbackFcn(app, @AverageNaClEditFieldValueChanged, true);
         app.LacticEditFieldLabel = uilabel(app.UIFigure);
         app.LacticEditFieldLabel.Text = 'Lactic';
         app.LacticEditField = uieditfield(app.UIFigure, 'numeric');
         app.LacticEditField.ValueChangedFcn = createCallbackFcn(app, @LacticEditFieldValueChanged, true);
         app.AceticEditFieldLabel = uilabel(app.UIFigure);
         app.AceticEditFieldLabel.Text = 'Acetic';
         app.AceticEditField = uieditfield(app.UIFigure, 'numeric');
         app.AceticEditField.ValueChangedFcn = createCallbackFcn(app, @AceticEditFieldValueChanged, true);
         app.CalculatedpHvalueLabel = uilabel(app.UIFigure);
         app.CalculatedpHvalueLabel.Text = 'Estimated pH value:';
         app.EstimatedpHvalueEditField = uieditfield(app.UIFigure, 'numeric');
         app.EstimatedpHvalueEditField.Editable = 'off';
         app.EstimatedpHvalueEditField.Value = 7;
         app.FormulationTable = uitable(app.UIFigure);
         app.FormulationTable.ColumnName = {'Ingredient'; 'Conc (%)'};
         app.FormulationTable.RowName = {};
         app.FormulationTable.ColumnEditable = [false true];
         app.FormulationTable.CellEditCallback = createCallbackFcn(app, @FormulationTableCellEdit, true);
         app.FormulationTable.Multiselect = 'off';
         app.FormulationLabel = uilabel(app.UIFigure);
         app.FormulationLabel.Text = 'Formulation';
         app.CitricEditFieldLabel = uilabel(app.UIFigure);
         app.CitricEditFieldLabel.Text = 'Citric';
         app.CitricEditField = uieditfield(app.UIFigure, 'numeric');
         app.CitricEditField.ValueChangedFcn = createCallbackFcn(app, @CitricEditFieldValueChanged, true);
         app.MalicEditFieldLabel = uilabel(app.UIFigure);
         app.MalicEditFieldLabel.Text = 'Malic';
         app.MalicEditField = uieditfield(app.UIFigure, 'numeric');
         app.MalicEditField.ValueChangedFcn = createCallbackFcn(app, @MalicEditFieldValueChanged, true);
         app.AscorbicEditFieldLabel = uilabel(app.UIFigure);
         app.AscorbicEditFieldLabel.Text = 'Ascorbic';
         app.AscorbicEditField = uieditfield(app.UIFigure, 'numeric');
         app.AscorbicEditField.ValueChangedFcn = createCallbackFcn(app, @AscorbicEditFieldValueChanged, true);
         app.ProtLacEditField = uieditfield(app.UIFigure, 'numeric');
         app.ProtLacEditField.Editable = 'off';
         app.ProtAceEditField = uieditfield(app.UIFigure, 'numeric');
         app.ProtAceEditField.Editable = 'off';
         app.ProtCitEditField = uieditfield(app.UIFigure, 'numeric');
         app.ProtCitEditField.Editable = 'off';
         app.ProtMalEditField = uieditfield(app.UIFigure, 'numeric');
         app.ProtMalEditField.Editable = 'off';
         app.ProtAscEditField = uieditfield(app.UIFigure, 'numeric');
         app.ProtAscEditField.Editable = 'off';
         app.ProtonatedLabel = uilabel(app.UIFigure);
         app.ProtonatedLabel.Text = 'Protonated';
         app.PhosphoricEditFieldLabel = uilabel(app.UIFigure);
         app.PhosphoricEditFieldLabel.Text = 'Phosphoric';
         app.PhosphoricEditField = uieditfield(app.UIFigure, 'numeric');
         app.PhosphoricEditField.ValueChangedFcn = createCallbackFcn(app, @PhosphoricEditFieldValueChanged, true);
         app.LacticPercentEditField = uieditfield(app.UIFigure, 'numeric');
         app.LacticPercentEditField.ValueChangedFcn = createCallbackFcn(app, @LacticPercentEditFieldValueChanged, true);
         app.AceticPercentEditField = uieditfield(app.UIFigure, 'numeric');
         app.AceticPercentEditField.ValueChangedFcn = createCallbackFcn(app, @AceticPercentEditFieldValueChanged, true);
         app.CitricPercentEditField = uieditfield(app.UIFigure, 'numeric');
         app.CitricPercentEditField.ValueChangedFcn = createCallbackFcn(app, @CitricPercentEditFieldValueChanged, true);
         app.MalicPercentEditField = uieditfield(app.UIFigure, 'numeric');
         app.MalicPercentEditField.ValueChangedFcn = createCallbackFcn(app, @MalicPercentEditFieldValueChanged, true);
         app.AscorbicPercentEditField = uieditfield(app.UIFigure, 'numeric');
         app.AscorbicPercentEditField.ValueChangedFcn = createCallbackFcn(app, @AscorbicPercentEditFieldValueChanged, true);
         app.PhosphoricPercentEditField = uieditfield(app.UIFigure, 'numeric');
         app.PhosphoricPercentEditField.ValueChangedFcn = createCallbackFcn(app, @PhosphoricPercentEditFieldValueChanged, true);
         app.PercentLabel = uilabel(app.UIFigure);
         app.PercentLabel.Text = 'Percent';
         app.mMLabel = uilabel(app.UIFigure);
         app.mMLabel.Text = 'mM';
         app.mMLabel_2 = uilabel(app.UIFigure);
         app.mMLabel_2.Text = 'mM';
         app.ProtGluEditField = uieditfield(app.UIFigure, 'numeric');
         app.ProtGluEditField.Editable = 'off';
         app.GluconicPercentEditField = uieditfield(app.UIFigure, 'numeric');
         app.GluconicPercentEditField.ValueChangedFcn = createCallbackFcn(app, @GluconicPercentEditFieldValueChanged, true);
         app.GluconicEditFieldLabel = uilabel(app.UIFigure);
         app.GluconicEditFieldLabel.Text = 'Gluconic';
         app.GluconicEditField = uieditfield(app.UIFigure, 'numeric');
         app.GluconicEditField.ValueChangedFcn = createCallbackFcn(app, @GluconicEditFieldValueChanged, true);
         app.NaClLabel = uilabel(app.UIFigure);
         app.NaClLabel.Text = '% NaCl: ';
         app.tBetaLabel = uilabel(app.UIFigure);
         app.tBetaLabel.Text = 'Estimated tBeta:';
         app.EstimatedtBetaEditField = uieditfield(app.UIFigure, 'numeric');
         app.ShowCurveButton = uibutton(app.UIFigure, 'push');
         app.ShowCurveButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCurveButtonPushed, true);
         app.ShowCurveButton.Text = 'Show Curve';
         app.ClearButton = uibutton(app.UIFigure, 'push');
         app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
         app.ClearButton.Text = {'Clear'; ''};
         app.TrimSwitchLabel = uilabel(app.UIFigure);
         app.TrimSwitchLabel.Text = 'Trim';
         app.TrimSwitch = uiswitch(app.UIFigure, 'rocker');
         app.TrimSwitch.ValueChangedFcn = createCallbackFcn(app, @TrimSwitchValueChanged, true);
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = IngredientDB

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @startupFcn)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end