classdef IngredientDB2 < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                      matlab.ui.Figure
      FileMenu                      matlab.ui.container.Menu
      OpendatabasefilesMenu         matlab.ui.container.Menu
      SaveformuladatabasefileMenu   matlab.ui.container.Menu
      QuitMenu                      matlab.ui.container.Menu
      HelpMenu                      matlab.ui.container.Menu
      WebBasedHelpMenu              matlab.ui.container.Menu
      AboutMenu                     matlab.ui.container.Menu
      PercentoftotalBufferingLabel  matlab.ui.control.Label
      IngredientPercentEditField    matlab.ui.control.NumericEditField
      IngredientEditFieldLabel      matlab.ui.control.Label
      AcidPercentEditField          matlab.ui.control.NumericEditField
      AcidLabel                     matlab.ui.control.Label
      TotalBufferingTable           matlab.ui.control.Table
      FormulationBufferingPercentagesLabel  matlab.ui.control.Label
      AcidtableLabel                matlab.ui.control.Label
      IngredienttableLabel          matlab.ui.control.Label
      ClearAcidTableButton          matlab.ui.control.Button
      ClearIngredientTableButton    matlab.ui.control.Button
      TrimCheckBox                  matlab.ui.control.CheckBox
      ConcEditField                 matlab.ui.control.NumericEditField
      ConcEditFieldLabel            matlab.ui.control.Label
      NaClEditField                 matlab.ui.control.NumericEditField
      NaClEditFieldLabel            matlab.ui.control.Label
      tBetaEditField                matlab.ui.control.NumericEditField
      tBetaEditFieldLabel           matlab.ui.control.Label
      pHEditField                   matlab.ui.control.NumericEditField
      pHEditFieldLabel              matlab.ui.control.Label
      acidTable                     matlab.ui.control.Table
      ingredientTable               matlab.ui.control.Table
      BCgraph                       matlab.ui.control.UIAxes
   end

   %-----------------------------------------------------------------------
   % General program information
   % 
   % This program uses an AAF class structure as a data class. The class is
   % implemented as app.ABDdata. Opening a new ingredient file or adding 
   % acid concentration(s) using the GUI functionality results updating the
   % app.ABDdata data members and tables. A subsequent call to postABDdata
   % result in taking the data from the appABDdata members and posting it
   % back to the GUI. In this way all edits are handled by the ABDdata
   % functions in the background prior to posting, including updating pH, 
   % tBeta, NaCl conc values, etc.
   %
   % NOTE: AAF is dependent on the ABD data class for ingredient buffer 
   % information either based on a saved formulation or a titration.  
   % ---------------------------------------------------------------------- 
   properties (Access = private)
      ABDdata = AAF;    %data class that holds formulation ABDs
      version = 1.00;   %current program version
   end
   
   methods (Access = private)
      
      function postABDdata(app)
         %survey acid concentrations, and ingABD, post all ABDs present
         ingtable = app.ABDdata.ingredienttable;   %get AAF ingredients
         app.ingredientTable.Data = ingtable;      %post ingredient table
         acidtable = app.ABDdata.acidtable;        %get AAF acid table
         acidtable(:,6) = [];               %remove MW col (NOT IN TABLE)
         app.acidTable.Data = acidtable;           %post acid table
         app.pHEditField.Value = app.ABDdata.pH;   %set pH 
         ingconc = sum(app.ABDdata.ingconcvec); %get ing conc sum
         acidconc = sum(app.ABDdata.acidconcvec); %get acid conc vec
         app.ConcEditField.Value = sum(ingconc + acidconc); %set total
         %NOTE:allABD titration_IS also set by NaCl box if no ingredients!
         app.NaClEditField.Value = app.ABDdata.allABDobj.titration_IS *5.84; 
         allABDobj = app.ABDdata.allABDobj;        %get the formulation
         titlestr = "Buffer Capacity Curve";       %make a graph title   
         trimbool = app.TrimCheckBox.Value;        %check trim box
         app.ABDdata.reset_trimbool(trimbool);     %resets all tBeta data
         %get buffering percentages for acids and ingredients
         app.tBetaEditField.Value = app.ABDdata.tBeta; %set tBeta
         totalbufferingtable = app.ABDdata.tBetatable; %get buffer table 
         totalbufferingtable.tBeta = [];           %remove tBeta col
         %sort by buffer table percent in descending order    
         totalbufferingtable = sortrows(totalbufferingtable,2,'descend');
         app.TotalBufferingTable.Data = totalbufferingtable; 
         %post percents
         app.IngredientPercentEditField.Value = app.ABDdata.ingpercent;
         app.AcidPercentEditField.Value = app.ABDdata.acidpercent;
         %plot graph of allABD
         if isempty(app.ABDdata.ingABDvar)
            plotABDarea_hBC(app.BCgraph,allABDobj,titlestr,0);
         else
            plotABDarea_hBC(app.BCgraph,allABDobj,titlestr,trimbool);
         end
         if isempty(app.ABDdata.ingABDvar)         %if no ingredients
            app.NaClEditField.Editable = "on";     %allow edits
         else                                      %ingredient present so
            app.NaClEditField.Editable = "off";    %don't allow edits
         end
      end
   end

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         acidtable = app.ABDdata.acidtable;     %get acid info from AAF
         app.acidTable.Data = acidtable;        %set table in app
         app.postABDdata();                     %post all data
      end

      % Menu selected function: OpendatabasefilesMenu
      function OpendatabasefilesMenuSelected(app, event)
         [file, path] = uigetfile('*.mat','open ingredient file', ...
             'MultiSelect','on');         %multiselectd open
         if path                          %check file path
            file = cellstr(file);         %because if one file, not cellstr
            nfiles = size(file,2);        %get number of files opened
            %get number of items in current app.ABDvar vector
            for i=1:nfiles                %for each file
               tempname = file{i};
               var = fullfile(path, file{i}); %make path
               temp = load(var);          %load the variable into matlab
               try 
                  namearray = app.ABDdata.ingnamelist; %get namelist
                  if ~any(strcmp(namearray,temp.ingredient.name)) %if not 
                     app.ABDdata.add_ingABD(temp.ingredient); %add 
                  end
               catch   %if error occurred, report the ingredient name
                  msgbox(['Error loading database file:',...
                  tempname], ...
                  'File error');
                  return
               end
            end
            %post results
            app.postABDdata();
         end
      end

      % Cell edit callback: acidTable
      function acidTableCellEdit(app, event)
         indices = event.Indices;
         newData = event.NewData;
         if ~isempty(indices)
            rowval = indices(1); %row of table
            acidname = char(app.acidTable.Data{rowval,2});  %name of acid
            saltofacid = app.acidTable.Data{rowval,3};
            colval = indices(2); %col of table
            switch colval
               case 3 %edit salt of acid check box
                  concpercent = app.acidTable.Data{rowval,4};
                  if concpercent > 0 %set salt checkbox:
                     app.ABDdata.set_acidCS(acidname,concpercent,newData);
                  else    %conc = 0, don't set salt checkbox:
                     app.acidTable.Data{rowval,colval} = false;
                  end
               case 4 %edit concentration and set mM
                  app.ABDdata.set_acidCS(acidname,newData,saltofacid);
               case 5 %edit mM and set conc
                  Molar = newData/1000;                     %convert to M
                  MW = app.ABDdata.acidtable{rowval,6};     %get MW
                  percent = MW*Molar*0.1;     %calc and set percent:
                  app.ABDdata.set_acidCS(acidname,percent,saltofacid);         
            end
            app.postABDdata();
         end
      end

      % Value changed function: TrimCheckBox
      function TrimCheckBoxValueChanged(app, event)
         app.postABDdata();
      end

      % Button pushed function: ClearIngredientTableButton
      function ClearIngredientTableButtonPushed(app, event)
         %clear all ABDs from ingredient list with clear function & post
         app.ABDdata.clear_ingABDvar();         %clear ingredient ABDs
         app.TrimCheckBox.Value = false;        %reset trim box
         app.postABDdata();                     %post empty list
      end

      % Double-clicked callback: ingredientTable
      function ingredientTableDoubleClicked(app, event)
         rowval = event.InteractionInformation.DisplayRow;
         displayColumn = event.InteractionInformation.DisplayColumn;
         %remove ABD data from ingredient list, first confirm deletion
         if ~isempty(app.ABDdata.ingABDvar) && (displayColumn == 2)
            len = length(app.ABDdata.ingABDvar);
            if rowval <= len %row is in range, confirm with dialog:
               name = app.ingredientTable.Data{rowval,2};
               quest = ['Delete ',name,' from ingredient list?']; 
               title = 'Remove Ingredient';
               answer = questdlg(quest,title,'Yes','No','Cancel','Cancel');
               if strcmp(answer,'Yes') %confirmed, remove and post results
                  app.ABDdata.remove_ingABD(name); 
                  app.postABDdata();            %post updates
               end
            end
         end
      end

      % Menu selected function: SaveformuladatabasefileMenu
      function SaveformuladatabasefileMenuSelected(app, event)
         ingredient = app.ABDdata.allABDobj;
         try
            [file, path] = uiputfile('*.mat', ...
               'Save Formulation Database File', ...
               char(ingredient.name));     %get name from ing ABD
            fullname = fullfile(path,file);
            save(fullname,"ingredient"); 
         catch %something went wrong
            msgbox('Error writing file, check file name/location?',...
               'Write Error');
         end
      end

      % Menu selected function: QuitMenu
      function QuitMenuSelected(app, event)
         delete(app); %close program
      end

      % Button pushed function: ClearAcidTableButton
      function ClearAcidTableButtonPushed(app, event)
         namelist = app.ABDdata.acidnamelist;   %get list of names
         len = length(namelist);                %lenght of list
         for i=1:len                            %for each name
            %set acid conc, salt-of-acid to zero for each name string
            app.ABDdata.set_acidCS(namelist{i},0,0); 
         end
         app.postABDdata();                      %post results
      end

      % Cell edit callback: ingredientTable
      function ingredientTableCellEdit(app, event)
         indices = event.Indices;            %get row-col indiced
         newData = event.NewData;            %get data value
         rowval = indices(1);                %get row
         colval = indices(2);                %get col  
         name = app.ingredientTable.Data.Name(rowval);
         if colval == 3                      %if edit in col 3 (conc)
           app.ABDdata.reset_ingABDconc(name,newData); %set new conc
           app.postABDdata();                %post results
         end
      end

      % Value changed function: NaClEditField
      function NaClEditFieldValueChanged(app, event)
         %value can only be edited if no ingredients
         value = app.NaClEditField.Value;    %get value
         IS = value/5.84;                    %convert %NaCl to IS
         app.ABDdata.reset_IS(IS);           %set ABDdata IS for acid
         app.postABDdata();                  %post results
      end

      % Menu selected function: AboutMenu
      function AboutMenuSelected(app, event)
         msgbox({'Version 1.0';'Contat Information';'Fred Breidt, PhD'; ...
            'US Department of Agriculture';...
            'Agricultural Resaerch Service'; ...
            'Market Quality and Handling Research Unit'; ...
            'Located on the campus of North Carolina State University'; ...
            '322 Schaub Hall, Box 7224'; ...
            'NC State University, Raleigh, NC, USA, 27695-7624'}, ...
               'IngredientDB');
      end

      % Menu selected function: WebBasedHelpMenu
      function WebBasedHelpMenuSelected(app, event)
         %help page for BC modeling on the USDA/ARS website:
         web(['https://www.ars.usda.gov/southeast-area/raleigh-' ...
            'nc/fsmqhru/docs/ingredientdb-help-item//']);
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'MATLAB App';
         app.FileMenu = uimenu(app.UIFigure);
         app.FileMenu.Text = 'File';
         app.OpendatabasefilesMenu = uimenu(app.FileMenu);
         app.OpendatabasefilesMenu.MenuSelectedFcn = createCallbackFcn(app, @OpendatabasefilesMenuSelected, true);
         app.OpendatabasefilesMenu.Accelerator = 'O';
         app.OpendatabasefilesMenu.Text = 'Open database file(s)';
         app.SaveformuladatabasefileMenu = uimenu(app.FileMenu);
         app.SaveformuladatabasefileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveformuladatabasefileMenuSelected, true);
         app.SaveformuladatabasefileMenu.Separator = 'on';
         app.SaveformuladatabasefileMenu.Accelerator = 'D';
         app.SaveformuladatabasefileMenu.Text = 'Save formula database file';
         app.QuitMenu = uimenu(app.FileMenu);
         app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
         app.QuitMenu.Separator = 'on';
         app.QuitMenu.Accelerator = 'Q';
         app.QuitMenu.Text = 'Quit';
         app.HelpMenu = uimenu(app.UIFigure);
         app.HelpMenu.Text = 'Help';
         app.WebBasedHelpMenu = uimenu(app.HelpMenu);
         app.WebBasedHelpMenu.MenuSelectedFcn = createCallbackFcn(app, @WebBasedHelpMenuSelected, true);
         app.WebBasedHelpMenu.Text = 'Web Based Help';
         app.AboutMenu = uimenu(app.HelpMenu);
         app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
         app.AboutMenu.Separator = 'on';
         app.AboutMenu.Text = 'About';
         app.BCgraph = uiaxes(app.UIFigure);
         title(app.BCgraph, 'Title')
         xlabel(app.BCgraph, 'X')
         ylabel(app.BCgraph, 'Y')
         zlabel(app.BCgraph, 'Z')
         app.ingredientTable = uitable(app.UIFigure);
         app.ingredientTable.ColumnName = {'N'; 'Name'; 'Conc %'};
         app.ingredientTable.RowName = {};
         app.ingredientTable.ColumnEditable = [false false true];
         app.ingredientTable.CellEditCallback = createCallbackFcn(app, @ingredientTableCellEdit, true);
         app.ingredientTable.DoubleClickedFcn = createCallbackFcn(app, @ingredientTableDoubleClicked, true);
         app.acidTable = uitable(app.UIFigure);
         app.acidTable.ColumnName = {'N'; 'Name'; '(Salt)'; 'Conc %'; 'mM'};
         app.acidTable.RowName = {};
         app.acidTable.ColumnEditable = [false false true true true];
         app.acidTable.CellEditCallback = createCallbackFcn(app, @acidTableCellEdit, true);
         app.acidTable.Multiselect = 'off';
         app.pHEditFieldLabel = uilabel(app.UIFigure);
         app.pHEditFieldLabel.Text = 'pH';
         app.pHEditField = uieditfield(app.UIFigure, 'numeric');
         app.pHEditField.Editable = 'off';
         app.tBetaEditFieldLabel = uilabel(app.UIFigure);
         app.tBetaEditFieldLabel.Text = 'tBeta';
         app.tBetaEditField = uieditfield(app.UIFigure, 'numeric');
         app.tBetaEditField.ValueDisplayFormat = '%.2f';
         app.tBetaEditField.Editable = 'off';
         app.NaClEditFieldLabel = uilabel(app.UIFigure);
         app.NaClEditFieldLabel.Text = 'NaCl %';
         app.NaClEditField = uieditfield(app.UIFigure, 'numeric');
         app.NaClEditField.Limits = [0 20];
         app.NaClEditField.ValueChangedFcn = createCallbackFcn(app, @NaClEditFieldValueChanged, true);
         app.ConcEditFieldLabel = uilabel(app.UIFigure);
         app.ConcEditFieldLabel.Text = 'Conc %';
         app.ConcEditField = uieditfield(app.UIFigure, 'numeric');
         app.ConcEditField.Editable = 'off';
         app.TrimCheckBox = uicheckbox(app.UIFigure);
         app.TrimCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrimCheckBoxValueChanged, true);
         app.TrimCheckBox.Text = 'Trim';
         app.ClearIngredientTableButton = uibutton(app.UIFigure, 'push');
         app.ClearIngredientTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearIngredientTableButtonPushed, true);
         app.ClearIngredientTableButton.Text = 'Clear';
         app.ClearAcidTableButton = uibutton(app.UIFigure, 'push');
         app.ClearAcidTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAcidTableButtonPushed, true);
         app.ClearAcidTableButton.Text = 'Clear';
         app.IngredienttableLabel = uilabel(app.UIFigure);
         app.IngredienttableLabel.Text = 'Ingredient table';
         app.AcidtableLabel = uilabel(app.UIFigure);
         app.AcidtableLabel.Text = 'Acid table';
         app.FormulationBufferingPercentagesLabel = uilabel(app.UIFigure);
         app.FormulationBufferingPercentagesLabel.Text = 'Formulation Buffering Percentages';
         app.TotalBufferingTable = uitable(app.UIFigure);
         app.TotalBufferingTable.ColumnName = {'Name'; 'tBeta %'};
         app.TotalBufferingTable.RowName = {};
         app.AcidLabel = uilabel(app.UIFigure);
         app.AcidLabel.Text = 'Acid %';
         app.AcidPercentEditField = uieditfield(app.UIFigure, 'numeric');
         app.AcidPercentEditField.ValueDisplayFormat = '%.2f';
         app.AcidPercentEditField.Editable = 'off';
         app.IngredientEditFieldLabel = uilabel(app.UIFigure);
         app.IngredientEditFieldLabel.Text = 'Ingredient % ';
         app.IngredientPercentEditField = uieditfield(app.UIFigure, 'numeric');
         app.IngredientPercentEditField.ValueDisplayFormat = '%.2f';
         app.IngredientPercentEditField.Editable = 'off';
         app.PercentoftotalBufferingLabel = uilabel(app.UIFigure);
         app.PercentoftotalBufferingLabel.Text = 'Percent of total Buffering';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = IngredientDB2

         runningApp = getRunningApp(app);

         % Check for running singleton app
         if isempty(runningApp)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)
         else

            % Focus the running singleton app
            figure(runningApp.UIFigure)

            app = runningApp;
         end

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end